classdef MetametricApp_Beta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        DataImportTab                   matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        ImportToChannelSelectionTabPanel  matlab.ui.container.Panel
        CheckBox_IsLeftColumnSelectedWavelength  matlab.ui.control.CheckBox
        Button_UseSelectedSPDs          matlab.ui.control.Button
        bIfWavelengthColumnSpecifyInterpolationPanel  matlab.ui.container.Panel
        DropDown_InterpolationType      matlab.ui.control.DropDown
        aIfNoWavelengthColumnSpecifyRangePanel  matlab.ui.container.Panel
        EditField_ImportedSPDWavelengthMax  matlab.ui.control.NumericEditField
        WavelengthRangeMinLabel_2       matlab.ui.control.Label
        EditField_ImportedSPDWavelengthMin  matlab.ui.control.NumericEditField
        WavelengthRangeMinLabel         matlab.ui.control.Label
        UITable_ImportedFile            matlab.ui.control.Table
        ClickImportTableToDisplaySpreadsheetPanel  matlab.ui.container.Panel
        EditField_ImportedFileName      matlab.ui.control.EditField
        Button_ImportTable              matlab.ui.control.Button
        ChannelSelectionTab             matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        ChannelSelectionTablePanel      matlab.ui.container.Panel
        UITable_ChannelSelection        matlab.ui.control.Table
        UIAxes_ChromDiagram             matlab.ui.control.UIAxes
        UIAxes_ImportedSPDs             matlab.ui.control.UIAxes
        OptimizationTab                 matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        OptimizationConstraintsPanel    matlab.ui.container.Panel
        Tree_Constraints                matlab.ui.container.CheckBoxTree
        UITable_Constraints             matlab.ui.control.Table
        OptimizationGoalPanel           matlab.ui.container.Panel
        EditField_NRuns                 matlab.ui.control.NumericEditField
        NumberOfInitialGuessesToMakeEditFieldLabel  matlab.ui.control.Label
        CheckBox_HonorBounds            matlab.ui.control.CheckBox
        DropDown_MaxOrMinGoal           matlab.ui.control.DropDown
        MaximizeOrMinimizeLabel         matlab.ui.control.Label
        Button_RunOptimization          matlab.ui.control.Button
        EditField_MaxIterations         matlab.ui.control.NumericEditField
        MaxOptimizationIterationsLabel  matlab.ui.control.Label
        DropDown_MetricGoal             matlab.ui.control.DropDown
        WhichMetricLabel                matlab.ui.control.Label
        ResultsAndExportTab             matlab.ui.container.Tab
        Panel_2                         matlab.ui.container.Panel
        Button_ExportOptimization       matlab.ui.control.Button
        UITable_OptimizationResults     matlab.ui.control.Table
        UIAxes_OptimSPD                 matlab.ui.control.UIAxes
        Tab                             matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        Panel6                          matlab.ui.container.Panel
        CheckBox2                       matlab.ui.control.CheckBox
        Slider                          matlab.ui.control.Slider
        SliderLabel                     matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        CheckBox                        matlab.ui.control.CheckBox
        UITable2                        matlab.ui.control.Table
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)


    end

    properties (Access = public)
        %% Imported Raw Values
        userSPDLibrary; % Description
        XYZuserSPDLibrary;
        importedFileName_Prop;
        xyuserSPDLibrary;
        wlIntUserImported_Prop
        myUiCon
        myUiFun
        %% Selected
        ImportTableColNumSelected 
        channelSelectedTF %logical vector to designate which are selected
        %% Optimization options
        % Max/Min Goal Type
        InitialGuessChannelPercents_Prop;

        outputSavePath; % Description
        allPossibleMetrics % Description

        ChannelMetrics % Description
        wlUserImported % Description
        ChannelMetricTable

        myOptimResults = struct();
        metricResultsTable % Description

        HonorBoundsTF
        myBestOptimResult = struct();

        wlVecProgram
        ImportTableHeaders
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            wrapper_startupFcn(app);
        end

        % Button pushed function: Button_ImportTable
        function Button_ImportTablePushed(app, event)
            wrapper_Button_ImportTablePushed(app,event);
        end

        % Button pushed function: Button_UseSelectedSPDs
        function Button_UseSelectedSPDsPushed(app, event)
            wrapper_Button_UseSelectedSPDsPushed(app,event);
        end

        % Button pushed function: Button_RunOptimization
        function Button_RunOptimizationPushed(app, event)
            wrapper_Button_RunOptimizationPushed(app,event);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            saveMyDefaults(app);
            delete(app)
        end

        % Cell edit callback: UITable_ChannelSelection
        function UITable_ChannelSelectionCellEdit(app, event)
            wrapper_UITable_ChannelSelectionCellEdit(app, event)
            
        end

        % Button pushed function: Button_ExportOptimization
        function Button_ExportOptimizationPushed(app, event)
            wrapper_Button_ExportOptimizationPushed(app, event)
            
        end

        % Callback function: Tree_Constraints
        function Tree_ConstraintsCheckedNodesChanged(app, event)
            checkedNodes = app.Tree_Constraints.CheckedNodes;
            if ~isempty(checkedNodes) %we can't dot index the tag if it is empty
                % remove the parent nodes that are checked
                myCheckedCellArray = {checkedNodes.NodeData};
                isNodeStructTF = cellfun( @isstruct, myCheckedCellArray)
                nodesToUse = checkedNodes(~isNodeStructTF); %get all nodes that are not struct
                metricsToList = {nodesToUse.Tag}';
    %             app.MetricsEvaluated = {checkedNodes.Tag}';
                makeUIConstraintTable(app,metricsToList); %remake table
            elseif isempty(checkedNodes)
                %for debug
                app.UITable_Constraints.Data = [];

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataImportTab = uitab(app.TabGroup);
            app.DataImportTab.Title = 'Data Import';
            app.GridLayout3 = uigridlayout(app.DataImportTab);
            app.ClickImportTableToDisplaySpreadsheetPanel = uipanel(app.GridLayout3);
            app.ClickImportTableToDisplaySpreadsheetPanel.Title = '1) Click "Import Table" To Display Spreadsheet';
            app.Button_ImportTable = uibutton(app.ClickImportTableToDisplaySpreadsheetPanel, 'push');
            app.Button_ImportTable.ButtonPushedFcn = createCallbackFcn(app, @Button_ImportTablePushed, true);
            app.Button_ImportTable.Text = 'Import Table';
            app.EditField_ImportedFileName = uieditfield(app.ClickImportTableToDisplaySpreadsheetPanel, 'text');
            app.EditField_ImportedFileName.Editable = 'off';
            app.EditField_ImportedFileName.Placeholder = 'Imported File Name';
            app.UITable_ImportedFile = uitable(app.GridLayout3);
            app.UITable_ImportedFile.RowName = {};
            app.aIfNoWavelengthColumnSpecifyRangePanel = uipanel(app.GridLayout3);
            app.aIfNoWavelengthColumnSpecifyRangePanel.Title = '2a) If No Wavelength Column: Specify Range';
            app.WavelengthRangeMinLabel = uilabel(app.aIfNoWavelengthColumnSpecifyRangePanel);
            app.WavelengthRangeMinLabel.Text = 'Wavelength Range Min';
            app.EditField_ImportedSPDWavelengthMin = uieditfield(app.aIfNoWavelengthColumnSpecifyRangePanel, 'numeric');
            app.EditField_ImportedSPDWavelengthMin.Value = 380;
            app.WavelengthRangeMinLabel_2 = uilabel(app.aIfNoWavelengthColumnSpecifyRangePanel);
            app.WavelengthRangeMinLabel_2.Text = 'Wavelength Range Max';
            app.EditField_ImportedSPDWavelengthMax = uieditfield(app.aIfNoWavelengthColumnSpecifyRangePanel, 'numeric');
            app.EditField_ImportedSPDWavelengthMax.Value = 780;
            app.bIfWavelengthColumnSpecifyInterpolationPanel = uipanel(app.GridLayout3);
            app.bIfWavelengthColumnSpecifyInterpolationPanel.Title = '2b) If Wavelength Column: Specify Interpolation';
            app.DropDown_InterpolationType = uidropdown(app.bIfWavelengthColumnSpecifyInterpolationPanel);
            app.DropDown_InterpolationType.Items = {'linear', 'spline'};
            app.DropDown_InterpolationType.Value = 'linear';
            app.ImportToChannelSelectionTabPanel = uipanel(app.GridLayout3);
            app.ImportToChannelSelectionTabPanel.Title = '4) Import To Channel Selection Tab';
            app.Button_UseSelectedSPDs = uibutton(app.ImportToChannelSelectionTabPanel, 'push');
            app.Button_UseSelectedSPDs.ButtonPushedFcn = createCallbackFcn(app, @Button_UseSelectedSPDsPushed, true);
            app.Button_UseSelectedSPDs.Text = 'Use Selected SPDs';
            app.CheckBox_IsLeftColumnSelectedWavelength = uicheckbox(app.ImportToChannelSelectionTabPanel);
            app.CheckBox_IsLeftColumnSelectedWavelength.Text = 'Left Column Selected Is Wavelength';
            app.ChannelSelectionTab = uitab(app.TabGroup);
            app.ChannelSelectionTab.Title = 'Channel Selection';
            app.GridLayout = uigridlayout(app.ChannelSelectionTab);
            app.UIAxes_ImportedSPDs = uiaxes(app.GridLayout);
            title(app.UIAxes_ImportedSPDs, 'Imported SPD Channels')
            xlabel(app.UIAxes_ImportedSPDs, 'Wavelength (nm)')
            ylabel(app.UIAxes_ImportedSPDs, 'Radiant Watts / nm')
            zlabel(app.UIAxes_ImportedSPDs, 'Z')
            app.UIAxes_ChromDiagram = uiaxes(app.GridLayout);
            title(app.UIAxes_ChromDiagram, 'CIE Diagram')
            xlabel(app.UIAxes_ChromDiagram, 'x Chromaticity')
            ylabel(app.UIAxes_ChromDiagram, 'y Chromaticity')
            zlabel(app.UIAxes_ChromDiagram, 'Z')
            app.ChannelSelectionTablePanel = uipanel(app.GridLayout);
            app.ChannelSelectionTablePanel.Title = 'Channel Selection Table';
            app.UITable_ChannelSelection = uitable(app.ChannelSelectionTablePanel);
            app.UITable_ChannelSelection.RowName = {};
            app.UITable_ChannelSelection.ColumnSortable = true;
            app.UITable_ChannelSelection.CellEditCallback = createCallbackFcn(app, @UITable_ChannelSelectionCellEdit, true);
            app.OptimizationTab = uitab(app.TabGroup);
            app.OptimizationTab.Title = 'Optimization';
            app.GridLayout2 = uigridlayout(app.OptimizationTab);
            app.OptimizationGoalPanel = uipanel(app.GridLayout2);
            app.OptimizationGoalPanel.Title = 'Optimization Goal';
            app.WhichMetricLabel = uilabel(app.OptimizationGoalPanel);
            app.WhichMetricLabel.Text = 'Which Metric?';
            app.DropDown_MetricGoal = uidropdown(app.OptimizationGoalPanel);
            app.MaxOptimizationIterationsLabel = uilabel(app.OptimizationGoalPanel);
            app.MaxOptimizationIterationsLabel.Text = 'Max Optimization Iterations';
            app.EditField_MaxIterations = uieditfield(app.OptimizationGoalPanel, 'numeric');
            app.EditField_MaxIterations.ValueDisplayFormat = '%.0f';
            app.Button_RunOptimization = uibutton(app.OptimizationGoalPanel, 'push');
            app.Button_RunOptimization.ButtonPushedFcn = createCallbackFcn(app, @Button_RunOptimizationPushed, true);
            app.Button_RunOptimization.Text = 'Run Optimization';
            app.MaximizeOrMinimizeLabel = uilabel(app.OptimizationGoalPanel);
            app.MaximizeOrMinimizeLabel.Text = 'Maximize Or Minimize?';
            app.DropDown_MaxOrMinGoal = uidropdown(app.OptimizationGoalPanel);
            app.CheckBox_HonorBounds = uicheckbox(app.OptimizationGoalPanel);
            app.CheckBox_HonorBounds.Text = {'Honor Bounds? '; '(Allow negative mix %)'};
            app.NumberOfInitialGuessesToMakeEditFieldLabel = uilabel(app.OptimizationGoalPanel);
            app.NumberOfInitialGuessesToMakeEditFieldLabel.Text = 'Number Of Initial Guesses To Make';
            app.EditField_NRuns = uieditfield(app.OptimizationGoalPanel, 'numeric');
            app.OptimizationConstraintsPanel = uipanel(app.GridLayout2);
            app.OptimizationConstraintsPanel.Title = 'Optimization Constraints';
            app.UITable_Constraints = uitable(app.OptimizationConstraintsPanel);
            app.UITable_Constraints.RowName = {};
            app.Tree_Constraints = uitree(app.OptimizationConstraintsPanel, 'checkbox');
            app.Tree_Constraints.Tag = 'myUITree';
            % Assign Checked Nodes
            app.Tree_Constraints.CheckedNodesChangedFcn = createCallbackFcn(app, @Tree_ConstraintsCheckedNodesChanged, true);
            app.ResultsAndExportTab = uitab(app.TabGroup);
            app.ResultsAndExportTab.Title = 'Results And Export';
            app.Panel_2 = uipanel(app.ResultsAndExportTab);
            app.UIAxes_OptimSPD = uiaxes(app.Panel_2);
            title(app.UIAxes_OptimSPD, 'Optimized SPD')
            xlabel(app.UIAxes_OptimSPD, 'X')
            ylabel(app.UIAxes_OptimSPD, 'Y')
            zlabel(app.UIAxes_OptimSPD, 'Z')
            app.UITable_OptimizationResults = uitable(app.Panel_2);
            app.UITable_OptimizationResults.RowName = {};
            app.Button_ExportOptimization = uibutton(app.Panel_2, 'push');
            app.Button_ExportOptimization.ButtonPushedFcn = createCallbackFcn(app, @Button_ExportOptimizationPushed, true);
            app.Button_ExportOptimization.Text = 'Export Optimization';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.GridLayout4 = uigridlayout(app.Tab);
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable2 = uitable(app.GridLayout4);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.CheckBox = uicheckbox(app.GridLayout4);
            app.Panel6 = uipanel(app.GridLayout4);
            app.Panel6.Title = 'Panel6';
            app.UIAxes2 = uiaxes(app.Panel6);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.SliderLabel = uilabel(app.Panel6);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.Panel6);
            app.CheckBox2 = uicheckbox(app.Panel6);
            app.CheckBox2.Text = 'Check Box2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MetametricApp_Beta

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end